classdef DockerPref < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        ISETDockerPrefsEditorLabel  matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        ISETDockerPrefPanel         matlab.ui.container.Panel
        PrefsPanelGrid              matlab.ui.container.GridLayout
        EditPrefButton              matlab.ui.control.StateButton
        WritePrefButton             matlab.ui.control.Button
        PfFieldsPanel               matlab.ui.container.Panel
        PfFieldsPanelGrid           matlab.ui.container.GridLayout
        PresetsPanel                matlab.ui.container.Panel
        PresetPanelGrid             matlab.ui.container.GridLayout
        GeneratePrefButton          matlab.ui.control.Button
        PresetDropDown              matlab.ui.control.DropDown
        PresetDropDownLabel         matlab.ui.control.Label
        PsFieldsPanel               matlab.ui.container.Panel
        PsFieldsPanelGrid           matlab.ui.container.GridLayout
    end

    
    properties (Constant, Access = private)
        DockerPresetTemplate =  struct( ...
            'label', '', ...
            'renderContext', '', ...
            'remoteHost', '', ...
            'device', '', ...
            'deviceID', '', ...
            'dockerImage', '', ...
            'remoteUser', '', ...
            'workDir', '', ...
            'PBRTResources', '' ...
        );
    end    
    properties (Access = private)
        % The data structures that the app uses
       
        dockerPresets = struct([]); % an array of presets 
        curPreset = struct();  % the "active" preset
        PresetGridHandle = struct(); % the grid
        PresetFields = struct();     % the EditFields

        curPrefs = struct();    % the current ISETDocker pref
        curPrefsChanged = false;
        PrefGridHandle = struct();
        PrefFields = struct();
        ignoredprefs = struct();
        aliasFields = struct( ...
            'deviceId',         'deviceID', ...
            'deviceID',         'deviceId' ...
        );
        ignoreFields = {
            'PBRTContainer', ...
            'batch', ...
            'remotePBRTResources'
        };

    
    end
    
    methods (Access = private)
        
        function [grid,handles] = buildFieldsPanel(app, parentPanel, baseStruct, pTag)

            % Get the empty struct
            fieldNames = fieldnames(baseStruct);
            numFields = numel(fieldNames);

            % Create a grid layout inside PrefsPanel
            grid = uigridlayout(parentPanel, [numFields, 2]);
            grid.RowSpacing = 5;
            grid.ColumnSpacing = 10;
            grid.RowHeight = repmat({30}, 1, numFields);
            grid.ColumnWidth = {'1x', '2x'};


            % Store component handles in a struct for easy reference
            %  app.([pTag 'Fields']) = struct();
            for i = 1:numFields
                fieldName = fieldNames{i};
                % for now just use the field name. Later, maybe put in spaces
                % labelText = insertSpaces(fieldName);
                % or
                % labelText = fieldtolabel(fieldName, pTag);
                %
                labelText = fieldName;


                % Create Label
                uilabel(grid, ...
                    'Text', labelText, ...
                    'HorizontalAlignment', ['left' ...
                    '']);

                % Create Edit Field
                handles.(fieldName) = uieditfield(grid, ...
                   'Tag', ['cur' pTag '.' fieldName], ...
                   'ValueChangedFcn', @(src, event)app.EditFieldValueChanged(src,event), ...
                   'Editable', 'off' ...
                );
            end
        end
        
        function populateEditFields(app, uiHandles, dataStruct)
            fields = fieldnames(dataStruct);
            for i = 1:numel(fields)
                f = fields{i};
                if isfield(uiHandles, f)
                    %Do we check for numbers here?  or should we do it
                    %earlier?
                    uiHandles.(f).Value = dataStruct.(f);
                end
            end
        end
        
        %% Read the presets from the docker/config directory.
        function  getDockerPresets(app)

            configDir = fullfile(piRootPath,'docker/config');
            presetsFile = fullfile(configDir,'dockerpresets.json');
        
            if ~isfile(presetsFile)
                warning('Presets file not found %s', presetsFile);
                return;
            end
            try
                rawtext = fileread(presetsFile);
                decodedData = jsondecode(rawtext);

                if ~isstruct(decodedData) || isempty(decodedData)
                    warning('JSON file is empty or has invalid structure.');
                    return;
                end
            catch err
                warning(err.identifier, 'Error reading JSON file: %s', err.message);
                return;
            end

            try
                newpresets = app.DockerPresetTemplate([]);
                newpresets = [newpresets ; decodedData];    
            catch concaterr
                warning(concaterr.identifier, 'Structure mismatch in JSON file: %s', concaterr.message);
                newpresets = salvagePresetsFile(app, decodedData);
            end

            app.dockerPresets = [app.dockerPresets ; newpresets];
        end
        
        %%  Read the Matlab ISETDocker prefs slots        
        function newpref = getDockerPrefs(app)
            % check to see if the pref exists
            newpref = app.DockerPresetTemplate;
            if ~ispref("ISETDocker")
                %if not return null prefs
                return
            end
            % get the ISETDocker pref
            prefs = getpref("ISETDocker");

            % cycle through the pref fields
            fields = fieldnames(prefs);
            for i = 1:numel(fields)
                prefName = fields{i};
                name = prefName;
                value = prefs.(name);
               
                % First make sure it's a valid field name
                if ismember(name, app.ignoreFields)
                    % this is where we deal with 'batch', 'PBRTContainer', etc.
                    % put it on a special list and go to the next
                        app.ignoredprefs.(name) = value;
                        continue;
                end

                %{
                % Should we allow the isetdocker struct and the ISETDocker prefs to be different
                % or force them to stay in sync?

                % is it aliased?
                if isfield(app.aliasFields, name)
                    aliased = app.aliasFields.(name);
                    % Is the new name a field
                    if isfield(newpref,aliased)
                        name = aliased;
                    else
                        error( ...
                            "bad entry in %s: %s aliased to %s, not a field in %s", ...
                            "app.aliasFields", name, aliased, "dockerPreset" ...
                        );
                    end
                elseif ~isfield(newpref, name)
                %}
                if ~isfield(newpref, name)
                    % not ignored, not aliased, not a known field
                    error("Bad entry in ISETDocker pref: %s is not a known field", ...
                            name);
                end
                % name is good, let's clean up value

                % we want value to be a char array or string
                if ~ischar(value) && ~isstring(value)
                    if isnumeric(value) && isscalar(value)
                        % if it's a simple scalar number, we make it into a string
                        value = num2str(value);
                    else
                        %Probably not ok
                        error("Bad entry in ISETDocker pref: %s.(%s) should be a char or string", name, value)
                    end
                end
                newpref.(name) = value;
            end

            % where should we keep the ones we recognize, but don't support
            % here.  Like the PBRTcontainer, which if we're changing
            % anything  in the prefs, we should probably clear.
            
            app.curPrefs = newpref;
            
        end

        %% Write out the current prefs as a new entry in the docker/config
        function newpref = saveDockerPrefs(app)
            % We should probably enable deleting from this file, as
            % well.
            disp('NYI');
        end
        
        %% NYI
        function newpresets = salvagePresetsFile(app, decodedData);
            warning("Salvage not yet implemented");
            return;
        end
        
        %% Find the preset from the label
        function preset = findpreset(app, label)
           preset = struct(); 
           
           for i = 1:numel(app.dockerPresets)
                if strcmpi(app.dockerPresets(i).label, label)
                    preset = app.dockerPresets(i);
                    return
                end
           end
           warning('Preset "%s" not found.', label)
        end
        
        %%
        function EditFieldValueChanged(app, src, event)
            % divvy src.Tag to get the correct field and set the new value
            fields = strsplit(src.Tag, '.');
            app.(fields{1}).(fields{2}) = src.Value;
            app.([fields{1} 'Changed']) = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            app.dockerPresets = app.DockerPresetTemplate([])
            %  then fill them in.
            %
            % what should we have as static defaults?  A local cpu version?
            % See if there's a json file that we can read
            % once we've dealt with the json file we can see if there is
            % an ISETDocker pref that wants to be made into a preset
            % if yes, read it in and build the menu
            % %otherwise
            %
            
            % get USER and other useful stuff 
            % where do we want to stash it?
            %
        
            % get the list of presets and intialize the dropdown
            getDockerPresets(app);
            % Update the dropdown menu from the preset array
            app.PresetDropDown.Items = {app.dockerPresets.label};
           % app.choosePresetDropDown.Items = {'Error reading file'}; % provide an error message in the dropdown.

            % This line blows up if dockerPresets is empty.  Catch it earler
            % and fail with a better error message
            app.curPreset = app.dockerPresets(1);
            [app.PresetGridHandle, app.PresetFields] = buildFieldsPanel( ...
                app, app.PsFieldsPanel, app.curPreset, 'Preset');

            populateEditFields(app, app.PresetFields, app.curPreset);
     

            % get the current Prefs
            getDockerPrefs(app);

            app.ISETDockerPrefPanel.Title = "Current ISETDocker Pref";
            %
            % create the EditFields in the panels
            % 
   
            [app.PrefGridHandle, app.PrefFields] = buildFieldsPanel( ...
                app, app.PfFieldsPanel, app.curPrefs, 'Prefs');

            % now fill them in

            populateEditFields(app, app.PrefFields, app.curPrefs);


%{
            % generic ValueChanged Function
            %
            app.pfDockerContextEF.Tag = 'pfDockerContextEF';
            app.pfDockerContextEF.ValueChangedFcn = ...
                @(src,event) app.pfGenericEFValueChanged(src,event);            
%}


        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            % When we choose a pull down, we reader the selection and
            % fill the other fields.
            label = app.PresetDropDown.Value;
            
            app.curPreset = findpreset(app,label);
            populateEditFields(app, app.PresetFields, app.curPreset);

    %           thisD = isetdocker;

           
            % might change where we get things...
            % this is all ease-of-use stuff to generate some presets.  
            % When we're doing stuff we just
            % use getpref for the current setup and just go.  We can figure
            % out wh
            %

        end

        % Button pushed function: GeneratePrefButton
        function GeneratePrefButtonPushed(app, event)
            % get the current preset
            % write it to the current pref
            % Let's try the simple way first
            app.curPrefs = app.curPreset;
            % update the current pref panel
            populateEditFields(app, app.PrefFields, app.curPrefs);
            newTitle = sprintf("New Pref from '%s'",app.curPreset.label);
            app.ISETDockerPrefPanel.Title = newTitle;
            app.curPrefsChanged = true;
        end

        % Button pushed function: WritePrefButton
        function WritePrefButtonPushed(app, event)
            % write out the new prefs

            %clear the old pref structure
            rmpref('ISETDocker');

            app.curPrefsChanged = false;
            % go through app.curPref and set each field in ISETDocker           
            fields = fieldnames(app.curPrefs);
            for i = 1:numel(fields)
                setpref('ISETDocker', fields{i}, app.curPrefs.(fields{i}));
            end
            app.curPrefsChanged = false;
%{
%   just make the isgnored fields disappear for now
            extras = fieldnames(app.ignoredprefs);
            for i = 1:numel(extras)
                setpref('ISETDocker', extras{i}, app.ignoredprefs.(extras{i}))
            end
%}
        end

        % Value changed function: EditPrefButton
        function EditPrefButtonValueChanged(app, event)
            % the value has been changed by the time this function is called
            editable = app.EditPrefButton.Value;

            % now put the individual edit fields into the same state
            fields = fieldnames(app.PrefFields)
            for i =1:numel(fields)
                app.PrefFields.(fields{i}).Editable = editable; 
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            % maybe check to see it anything should be saved?
            app.delete ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PresetsPanel = uipanel(app.GridLayout2);
            app.PresetsPanel.Title = 'Presets';
            app.PresetPanelGrid = uigridlayout(app.PresetsPanel);
            app.PsFieldsPanel = uipanel(app.PresetPanelGrid);
            app.PsFieldsPanelGrid = uigridlayout(app.PsFieldsPanel);
            app.PresetDropDownLabel = uilabel(app.PresetPanelGrid);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.PresetPanelGrid);
            app.PresetDropDown.Items = {'Local CPU', '<New Preset>'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetDropDown.Placeholder = '<Choose a Preset>';
            app.PresetDropDown.Value = 'Local CPU';
            app.GeneratePrefButton = uibutton(app.PresetPanelGrid, 'push');
            app.GeneratePrefButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePrefButtonPushed, true);
            app.GeneratePrefButton.Text = 'Generate Pref';
            app.ISETDockerPrefPanel = uipanel(app.GridLayout2);
            app.ISETDockerPrefPanel.Title = 'ISETDocker Pref';
            app.PrefsPanelGrid = uigridlayout(app.ISETDockerPrefPanel);
            app.PfFieldsPanel = uipanel(app.PrefsPanelGrid);
            app.PfFieldsPanelGrid = uigridlayout(app.PfFieldsPanel);
            app.WritePrefButton = uibutton(app.PrefsPanelGrid, 'push');
            app.WritePrefButton.ButtonPushedFcn = createCallbackFcn(app, @WritePrefButtonPushed, true);
            app.WritePrefButton.Tooltip = {'use setpref() to create a new version of the ISETDocker pref'};
            app.WritePrefButton.Text = 'Write Pref';
            app.EditPrefButton = uibutton(app.PrefsPanelGrid, 'state');
            app.EditPrefButton.ValueChangedFcn = createCallbackFcn(app, @EditPrefButtonValueChanged, true);
            app.EditPrefButton.Tooltip = {'Make the pref fields editable'};
            app.EditPrefButton.Text = 'Edit Pref';
            app.ISETDockerPrefsEditorLabel = uilabel(app.GridLayout);
            app.ISETDockerPrefsEditorLabel.Text = 'ISETDocker Prefs Editor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DockerPref

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end